classdef somatosensory < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Somatosensory                   matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        Label                           matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        coherenceanalysisDropDownLabel  matlab.ui.control.Label
        coherenceanalysisDropDown       matlab.ui.control.DropDown
        DropDown_2                      matlab.ui.control.Label
        EEGMEGchannelsKnobLabel         matlab.ui.control.Label
        EEGMEGchannelsKnob              matlab.ui.control.DiscreteKnob
        ClinicalDataButtonGroup         matlab.ui.container.ButtonGroup
        EEGButton                       matlab.ui.control.RadioButton
        MEGButton                       matlab.ui.control.RadioButton
        GLMPSEPButton                   matlab.ui.control.Button
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        ZeroPadLabel                    matlab.ui.control.Label
        FsLabel                         matlab.ui.control.Label
        spectralanalysisDropDownLabel   matlab.ui.control.Label
        spectralanalysisDropDown        matlab.ui.control.DropDown
        ForwardSimulationLabel          matlab.ui.control.Label
        GrangerPredictionProximationDropDownLabel  matlab.ui.control.Label
        GrangerPredictionProximationDropDown  matlab.ui.control.DropDown
        GscatterDropDownLabel           matlab.ui.control.Label
        GscatterDropDown                matlab.ui.control.DropDown
        SyntheticDataButtonGroup        matlab.ui.container.ButtonGroup
        IAS_MAPButton                   matlab.ui.control.RadioButton
        CMButton                        matlab.ui.control.RadioButton
        EvaluationDropDownLabel         matlab.ui.control.Label
        EvaluationDropDown              matlab.ui.control.DropDown
        SVMOutlierDetectionButton       matlab.ui.control.Button
        Win_lenEditFieldLabel           matlab.ui.control.Label
        Win_lenEditField                matlab.ui.control.NumericEditField
        FpassEditFieldLabel             matlab.ui.control.Label
        FpassEditField                  matlab.ui.control.NumericEditField
        FstopEditFieldLabel             matlab.ui.control.Label
        FstopEditField                  matlab.ui.control.NumericEditField
        TstartEditFieldLabel            matlab.ui.control.Label
        TstartEditField                 matlab.ui.control.NumericEditField
        TendEditFieldLabel              matlab.ui.control.Label
        TendEditField                   matlab.ui.control.NumericEditField
        SlideWindowButton               matlab.ui.control.StateButton
        HanningWindowButton             matlab.ui.control.StateButton
        cross_lenEditFieldLabel         matlab.ui.control.Label
        cross_lenEditField              matlab.ui.control.NumericEditField
        FreqEditFieldLabel              matlab.ui.control.Label
        FreqEditField                   matlab.ui.control.NumericEditField
        Source_cross_SpectrumButton     matlab.ui.control.Button
    end


    properties (Access = private)
        Plot = "";
        %measurements;
        zef_EEG struct = load("zef_eeg.mat"); % EEG clinical data
        zef_MEG struct = load("zef_meg.mat"); % MEG clinical data
        EEG_T(72,240);
        MEG_T(271,240);
        EEG_Z(72,240);
        MEG_Z(271,240);
        EEG(1,240);
        MEG(1,240);
        ERP_BE;
        ERP_ME;
        Windowlength = 10;
        clength = 75;
        zerop;
        fs;
        Fpass;
        fstop;
        Tstart;
        Tend;
        L;
        H;
        f;
        PDCX;
        DTFX;
        CS;
        SCS;
        SNR;
        E;
        X1;% for machine learning training data1(IAS_MAP)
% 1. amplitude difference, 2. angle difference, 3. location difference, 4.Beta 
% 5. Hyperprior(1. gamma 2. inverse gamma) 6. Noise(0.02 0.05) 7. localization (1. thalamus 2. somatosensory 3. thalamus_pair 4. somatosensory_pair)
        Y1;% for theta0 labels data1
        X2;% for machine learning training data2(CM)
        Y2;% for theta0 labels data2 
        Y11; % for whole data1 location labels 
        Y21; % for whole data1 location labels 
        Y31; % for whole data1 location labels 
        Y12; % for whole data1 location labels 
        Y22; % for whole data1 location labels 
        Y32; % for whole data1 location labels 
        svInd;
        Features;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.zef_EEG = load("zef_eeg.mat"); % EEG clinical data
            app.zef_MEG = load("zef_meg.mat"); % MEG clinical data
            if isfield(app.zef_MEG.zef_MEG, 'sensors')
                MEG_sensors = app.zef_MEG.zef_MEG.sensors;
            end
            app.Plot = plot(app.UIAxes,MEG_sensors(:,1),MEG_sensors(:,2),'o');
            title(app.UIAxes,'MEG sensors');
            
            if app.IAS_MAPButton.Value == 1
                load('data1') %dipole restruction results with IAS-MAP estimators 
                app.X1 = X1; % for machine learning training data1(IAS)
                app.Y1 = Y1; % for theta0 labels data1
                app.Y11 = Y11; % for whole data1 location labels 
                app.Y21 = Y21; % for whole data1 location labels 
                app.Y31 = Y31; % for whole data1 location labels  
            else    
                load('data2') %dipole restruction results with CM estimators
                app.X2 = X2; % for machine learning training data2(CM)
                app.Y2 = Y2; % for theta0 labels data2
                app.Y12 = Y12; % for whole data1 location labels 
                app.Y22 = Y22; % for whole data1 location labels 
                app.Y32 = Y32; % for whole data1 location labels
            end                
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            xlabel(app.UIAxes, 'Time ms')
            ylabel(app.UIAxes, 'Amplitude uV')
            if app.EEGButton.Value == 1
%                app.measurements = zef_EEG.measurements
%                    app.zef_EEG = load("zef_EEG"); % EEG clinical data
                    eeg = app.zef_EEG.zef_EEG.measurements;
                    erp_BE = mean(eeg(:,1:120),2);
                    erp_BE = repmat(erp_BE,1,360);
                    eeg = eeg-erp_BE;
                    app.EEG_T = eeg(:,121:360);
                    app.ERP_BE = erp_BE(:,121:360);
                    switch value
                        case 'Base Line Correction'                    
                            app.Plot = plot(app.UIAxes, 1:240, app.EEG_T, 'b', 1:240, app.ERP_BE, 'r')
                            title(app.UIAxes,'EEG measurements after baseline correction') 
                            
                        case 'Base Line Corrected Z_Transformation'
                            app.EEG_Z = zscore(app.EEG_T);
                            app.Plot = plot(app.UIAxes, 1:240, app.EEG_Z, 'b', 1:240, app.ERP_BE, 'r')
                            title(app.UIAxes,'EEG measurements after Z transform') 
                            
                        case 'Base Line Corrected L_Transformation'
                            EEG_L = log(app.EEG_T);
                            app.Plot = plot(app.UIAxes, 1:240, EEG_L, 'b', 1:240, app.ERP_BE, 'r')
                            title(app.UIAxes,'EEG measurements after L transform') 

                    end
            end
                    
            if app.MEGButton.Value == 1
%                    app.zef_MEG = load("zef_MEG"); % EEG clinical data
                    meg = app.zef_MEG.zef_MEG.measurements;
                    erp_ME = mean(meg(:,1:120),2);
                    erp_ME = repmat(erp_ME,1,360);
                    meg = meg-erp_ME;
                    app.MEG_T = meg(:,121:360);
                    app.ERP_ME = erp_ME(:,121:360);
                    switch value
                        case 'Base Line Correction'                    
                            app.Plot = plot(app.UIAxes, 1:240, app.MEG_T, 'b', 1:240, app.ERP_ME, 'r')
                            title(app.UIAxes,'MEG measurements after baseline correction') 
                            
                        case 'Base Line Corrected Z_Transformation'
                            app.MEG_Z = zscore(app.MEG_T);
                            app.Plot = plot(app.UIAxes, 1:240, app.MEG_Z, 'b', 1:240, app.ERP_ME, 'r')
                            title(app.UIAxes,'MEG Z Transformation after baseline correction')
                            
                        case 'Base Line Corrected L_Transformation'
                            MEG_L = log(app.MEG_T);
                            app.Plot = plot(app.UIAxes, 1:240, MEG_L, 'b', 1:240, app.ERP_ME, 'r')
                            title(app.UIAxes,'MEG L Transformation after baseline correction')

                    end
            end
                                       
        end

        % Value changed function: EEGMEGchannelsKnob
        function EEGMEGchannelsKnobValueChanged(app, event)
            value = app.EEGMEGchannelsKnob.Value;
            if app.MEGButton.Value == 1
                switch value
                    case '5(a)'
                        app.MEG = app.MEG_Z(5,:)
                    case '13(b)'
                        app.MEG = app.MEG_Z(13,:)
                    case '27(1)'
                        app.MEG = app.MEG_Z(27,:)
                    otherwise                  
                        assert(value == {'5(a)', '13(b)', '27(1)'}, msgbox('please choose the channels for MEG somatosensory'))                
                        app.Somatosensory.CloseRequestFcn()
                end
                for t = 1:240
                    pvalue(t) = ttest(app.MEG(1:t), app.ERP_ME(1:t))
                end
                app.Plot = plot(app.UIAxes, 1:240, app.MEG, 'b', 1:240, app.ERP_ME, 'r', 1:240, pvalue, 'g')                            
                title(app.UIAxes, 'MEG: SEP ERP Pvalue')
            end
            if app.EEGButton.Value == 1
                switch value
                    case '5(a)'
                        app.EEG = app.EEG_Z(5,:)
                    case '33(b)'
                        app.EEG = app.EEG_Z(33,:)
                    case '18(1)'
                        app.EEG = app.EEG_Z(18,:);
                    otherwise
                        assert(value == {'5(a)', '33(b)', '18(1)'}, msgbox('please choose the channels for EEG somatosensory'))
                        app.Somatosensory.CloseRequestFcn()                
                end
                for t = 1:240             
                    pvalue(t) = ttest(app.EEG(1:t), app.ERP_BE(1:t))
                end
                app.Plot = plot(app.UIAxes, 1:240, app.EEG, 'b', 1:240, app.ERP_BE, 'r', 1:240, pvalue, 'g')
                title(app.UIAxes, 'EEG: SEP ERP Pvalue')
            end
        end

        % Button pushed function: GLMPSEPButton
        function GLMPSEPButtonPushed(app, event)
             if app.EEGButton.Value == 1
                n = size(app.EEG_Z,2)
                GLMP = sum((app.EEG_Z-repmat(mean(app.EEG_Z,1),72,1)).^2,1)/n;
                app.Plot = plot(app.UIAxes, 1:240, app.EEG_Z, 'b', 1:240, GLMP,'r')
                title(app.UIAxes, 'EEG GLMP')

             end
             if app.MEGButton.Value == 1
                n = size(app.MEG_Z,2)
                GLMP = sum((app.MEG_Z-repmat(mean(app.MEG_Z,1),271,1)).^2,1)/n;
                app.Plot = plot(app.UIAxes, 1:240, app.MEG_Z, 'b', 1:240, GLMP,'r')
                title(app.UIAxes, 'MEG GLMP')
             end
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            app.zerop = app.EditField_3.Value;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.fs = app.EditField_2.Value;
            
        end

        % Value changed function: coherenceanalysisDropDown
        function coherenceanalysisDropDownValueChanged(app, event)
            value = app.coherenceanalysisDropDown.Value;
            if app.EEGButton.Value == 1
                X = app.EEG_Z
                switch value 
                    case 'MS coherence'
                        C(1,:) = mscohere(X(5,:),X(18,:),hamming(100),[],[],app.fs);
                        C(2,:) = mscohere(X(18,:),X(33,:),hamming(100),[],[],app.fs);
                        C(3,:) = mscohere(X(33,:),X(5,:),hamming(100),[],[],app.fs);
                        Cpa1 = (C(1,:)-C(3,:).*C(2,:))./sqrt((1-C(3,:).^2).*(1-C(2,:).^2));
                        area = [{'3a'},{'3b'},{'1'},{'3a'}];
                        plot(app.UIAxes,Cpa1);
                        title(app.UIAxes,['partial correlation remove ',area(4)])
                        figure,
                        for i =1:3
                            subplot(3,1,i)
                            plot(C(i,:));
                            title(['EEG coherence between area ',area(i),'& ',area(i+1)])
                        end                       
                    case 'MVAR coherence'
                        H_mul = MVAR(X,1,240);
                        figure,
                        for i = 1:4
                            subplot(2,2,i)
                            app.H = {};
                            app.H{i}= H_mul(1:67*i,1:67*i);
                            imagesc(abs(app.H{i}));
                            title(['EEG coherence analysis from time 0 to ',int2str(67*i)])
                        end
                    case 'BIC'
                        [bic,p] = BIC(app.H);
                end
            else
                X = app.MEG_Z
                switch value 
                    case 'MS coherence'
                        C(1,:) = mscohere(X(5,:),X(13,:),hamming(100),[],[],app.fs);
                        C(2,:) = mscohere(X(13,:),X(27,:),hamming(100),[],[],app.fs);
                        C(3,:) = mscohere(X(27,:),X(5,:),hamming(100),[],[],app.fs);
                        Cpa1 = (C(1,:)-C(3,:).*C(2,:))./sqrt((1-C(3,:).^2).*(1-C(2,:).^2));
                        area = [{'3a'},{'3b'},{'1'},{'3a'}];
                        plot(app.UIAxes,Cpa1);
                        title(app.UIAxes,['MEG partial correlation remove ',area(4)])
                        figure,
                        for i =1:3
                            subplot(3,1,i)
                            plot(C(i,:));
                            title(['MEG coherence between area ',area(i),'& ',area(i+1)])
                        end                        
                    case 'MVAR coherence'
                        H_mul = MVAR(X,1,240);
                        figure,
                        for i = 1:4
                            subplot(2,2,i)
                            app.H = {};
                            app.H{i}= H_mul(1:67*i,1:67*i);
                            imagesc(abs(app.H{i}));
                            title(['MEG coherence analysis from time 0 to ',int2str(67*i)])
                        end
                    case 'BIC'
                        [bic,p] = BIC(app.H);
                end
            end                  
        end

        % Value changed function: GrangerPredictionProximationDropDown
        function GrangerPredictionProximationDropDownValueChanged(app, event)
            value = app.GrangerPredictionProximationDropDown.Value;
            if app.EEGButton.Value == 1
                switch value 
                    case 'PDC'
                        [j,app.PDCX] = PDC(app.EEG_Z, app.f);
                    case 'DTF'
                        [j,app.DTFX] = DTF(app.EEG_Z, app.f);
                end  
            else
                switch value 
                    case 'PDC'
                        [j,app.PDCX] = PDC(app.MEG_Z, app.f);
                    case 'DTF'
                        [j,app.DTFX] = DTF(app.MEG_Z, app.f);
                end
            end
        end

        % Value changed function: spectralanalysisDropDown
        function spectralanalysisDropDownValueChanged(app, event)
            value = app.spectralanalysisDropDown.Value;
            switch value
                case '(Sensor)_Cross_Spectrum'
                    [app.CS, app.E, app.SNR] = cross_spectral(app.MEG_Z, 30, 5);
                case 'SNR'
                    figure,
                    spectrogram(app.SNR(1:size(app.CS,2):size(app.CS,2)^2));
                    title('Source cross sprctral SNR')
            end
        end

        % Callback function
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            switch value 
                case 'Source_Cross_Spectrum'
                    app.SCS = source_cross_spectral(app.L,app.MEG_Z, 30, 5);
            end
        end

        % Selection changed function: ClinicalDataButtonGroup
        function ClinicalDataButtonGroupSelectionChanged(app, event)
            if event.OldValue ~= event.NewValue
                if app.EEGButton.Value == 1
                    EEG_sensors = app.zef_EEG.zef_EEG.sensors;
                    app.Plot = plot(app.UIAxes,EEG_sensors(:,1),EEG_sensors(:,2),'o');
                    title(app.UIAxes,'EEG sensors');            
                else
                    MEG_sensors = app.zef_MEG.zef_MEG.sensors;
                    app.Plot = plot(app.UIAxes,MEG_sensors(:,1),MEG_sensors(:,2),'o');
                    title(app.UIAxes,'MEG sensors');
                end
            end
        end

        % Value changed function: SlideWindowButton
        function SlideWindowButtonValueChanged(app, event)
            f = zeros(1, app.Tend-app.Tstart-app.Windowlength-app.zerop)
            xlabel(app.UIAxes,'Frequency Hz')
            ylabel(app.UIAxes,'Power uV')
       %     steplen = app.Windowlength + app.zerop - app.clength;
            if app.EEGButton.Value == 1
                for t = app.Tstart:app.Tend-app.Windowlength-app.zerop-1
                    f(1,t-app.Tstart+1) = mean((fft([app.EEG(1,t+1:t+app.Windowlength) zeros(1,app.zerop)])).^2,2)
                end

                app.Plot = plot(app.UIAxes,(app.Tstart+1):(app.Tend-app.Windowlength-app.zerop), f);
                title(app.UIAxes,'EEG slide window FFT spectrum');
                figure,
                plot(1:(app.Windowlength+app.zerop), [app.EEG(1,1:app.Windowlength) zeros(1,app.zerop)],'-o')
                title('EEG slide window with zero padding');
            else
                for t = app.Tstart:app.Tend-app.Windowlength-app.zerop-1
                    f(1,t-app.Tstart+1) = mean((fft([app.MEG(1,t+1:t+app.Windowlength) zeros(1,app.zerop)])).^2,2)
                end
                app.Plot = plot(app.UIAxes,(app.Tstart+1):(app.Tend-app.Windowlength-app.zerop), f);
                title(app.UIAxes,'MEG slide window FFT spectrum');
                
                figure,
                plot(1:(app.Windowlength+app.zerop), [app.MEG(1,1:app.Windowlength) zeros(1,app.zerop)],'-o')
                title('MEG slide window with zero padding');
                
            end
        end

        % Value changed function: Win_lenEditField
        function Win_lenEditFieldValueChanged(app, event)
            app.Windowlength = app.Win_lenEditField.Value;
            
        end

        % Value changed function: cross_lenEditField
        function cross_lenEditFieldValueChanged2(app, event)
            app.clength = app.cross_lenEditField.Value;
            
        end

        % Value changed function: FpassEditField
        function FpassEditFieldValueChanged(app, event)
            app.Fpass = app.FpassEditField.Value;
            
        end

        % Value changed function: FstopEditField
        function FstopEditFieldValueChanged(app, event)
            app.fstop = app.fstopEditField.Value;
            
        end

        % Value changed function: TstartEditField
        function TstartEditFieldValueChanged(app, event)
            app.Tstart = app.TstartEditField.Value;
            
        end

        % Value changed function: TendEditField
        function TendEditFieldValueChanged(app, event)
            app.Tend = app.TendEditField.Value;
            
        end

        % Value changed function: HanningWindowButton
        function HanningWindowButtonValueChanged(app, event)
            if app.EEGButton.Value == 1
                X = fft(app.EEG)
                n = 2*app.Windowlength+1
                w = [0.5 + 0.5*sinc(2*pi*(1:n)) zeros(1, app.zerop)];
          %      X_W = [X(1:len(w))].*fft(w);
          %      M_X = mean(X_W, 2);
                for i = 1:240-n-app.zerop
                    X_W(i: i+length(w)-1) = X(i:i+length(w)-1) .* fft(w)
                    M_X(i) = mean(X_W(i:i+length(w)-1), 2)
                end
                app.Plot = plot(app.UIAxes, 1:240-n-app.zerop, M_X.^2)
                title(app.UIAxes, 'Spectrum FFT by Hanning window with zero padding')
                figure,
                plot(1:length(w), fft(w),'-o')
                title('FFT Hanning window with zero padding')
            else
                X = fft(app.MEG)
                n = 2*app.Windowlength+1
                w = [0.5 + 0.5*sinc(2*pi*(1:n)) zeros(1, app.zerop)];
          %      X_W = [X(1:len(w))].*fft(w);
          %      M_X = mean(X_W, 2);
                for i = 1:240-n-app.zerop
                    X_W(i: i+length(w)-1) = X(i:i+length(w)-1) .* fft(w)
                    M_X(i) = mean(X_W(i:i+length(w)-1), 2)
                end
                app.Plot = plot(app.UIAxes, 1:240-n-app.zerop, M_X.^2)
                title(app.UIAxes, 'Spectrum FFT by Hanning window with zero padding')
                figure,
                plot(1:length(w), fft(w),'-o')
                title('FFT Hanning window with zero padding')
            end
        end

        % Value changed function: FreqEditField
        function FreqEditFieldValueChanged(app, event)
            app.f = app.FreqEditField.Value;          
        end

        % Button pushed function: Source_cross_SpectrumButton
        function Source_cross_SpectrumButtonPushed(app, event)
            load('zef_MEG_L.mat')
            app.L = zef_MEG_L;
            [app.E, app.SNR, app.SCS] = source_cross_spectral(app.L,app.MEG_Z, 30, 5);
   
        end

        % Button pushed function: SVMOutlierDetectionButton
        function SVMOutlierDetectionButtonPushed(app, event)
            if app.IAS_MAPButton.Value == 1
                y = ones(size(app.X1,1),1);
                rng(1);
                SVMModel = fitcsvm([app.X1(:,1) app.X1(:,2)],y,'KernelScale','auto','Standardize','on',...
                    'OutlierFraction',0.1);
            
                app.svInd = SVMModel.IsSupportVector;
                h = 1; % Mesh grid step size
                % [X1,X2,X3] = meshgrid(min(X(:,1)):h:max(X(:,1)),...
                %     min(X(:,2)):h:max(X(:,2)),min(X(:,3)):h:max(X(:,3)));
                [x1,x2] = meshgrid(min(app.X1(:,1)):h:max(app.X1(:,1)),min(app.X1(:,2)):h:max(app.X1(:,2)));
                % [~,score] = predict(SVMModel,[X1(:),X2(:),X3(:)]);
                [~,score] = predict(SVMModel,[x1(:),x2(:)]);
            
                % scoreGrid = reshape(score,size(X1,1),size(X2,2),size(X3,3));
                scoreGrid = reshape(score,size(x1,1),size(x2,2)); 
                figure(5)
                % plot3(X(:,3),X(:,2),X(:,1),'k.')
                plot(app.X1(:,1),app.X1(:,2),'k.',app.X1(app.svInd,1),app.X1(app.svInd,2),'ro')
                % plot3(X(svInd,3),X(svInd,2),X(svInd,1),'ro','MarkerSize',10)
                %contour3([X(svInd,3),X(svInd,1)])
                hold('on')
                contour(x1,x2, scoreGrid)
                colorbar;
                xlim = [-5 50];
                ylim = [-10 100];
                title('{\bf Data1 Outlier Detection via One-Class SVM}')
                xlabel('Location difference (mm)')
                %ylabel('Angle difference (mm)')
                ylabel('Amplitude difference (mm)')
                legend('Observation','Support Vector')
                
                CVSVMModel = crossval(SVMModel);
                [~,scorePred] = kfoldPredict(CVSVMModel);
                outlierRate = mean(scorePred<0) 
                hold('off')
                
            else 
                y = ones(size(app.X2,1),1);
                rng(1);
                SVMModel = fitcsvm([app.X2(:,1) app.X2(:,2)],y,'KernelScale','auto','Standardize','on',...
                    'OutlierFraction',0.1);
                
                app.svInd = SVMModel.IsSupportVector;
                h = 1; % Mesh grid step size
                % [X1,X2,X3] = meshgrid(min(X(:,1)):h:max(X(:,1)),...
                %     min(X(:,2)):h:max(X(:,2)),min(X(:,3)):h:max(X(:,3)));
                [x1,x2] = meshgrid(min(app.X2(:,1)):h:max(app.X2(:,1)),min(app.X2(:,2)):h:max(app.X2(:,2)));
                % [~,score] = predict(SVMModel,[X1(:),X2(:),X3(:)]);
                [~,score] = predict(SVMModel,[x1(:),x2(:)]);
                
                % scoreGrid = reshape(score,size(X1,1),size(X2,2),size(X3,3));
                scoreGrid = reshape(score,size(x1,1),size(x2,2)); 
                figure(5)
                % plot3(X(:,3),X(:,2),X(:,1),'k.')
                plot(app.X2(:,1),app.X2(:,2),'k.',app.X2(app.svInd,1),app.X2(app.svInd,2),'ro')
%                hold on
                % plot3(X(svInd,3),X(svInd,2),X(svInd,1),'ro','MarkerSize',10)
%                plot(app.UIAxes,app.X2(svInd,1),app.X2(svInd,2),'ro','MarkerSize',5)
                %contour3([X(svInd,3),X(svInd,1)])
                hold('on')
                contour(x1,x2, scoreGrid)
                colorbar;
                xlim = [-5 50];
                ylim = [-10 100];
                title('{\bf Data1 Outlier Detection via One-Class SVM}')
                xlabel('Location difference (mm)')
                %ylabel('Angle difference (mm)')
                ylabel('Amplitude difference (mm)')
                legend('Observation','Support Vector')
                hold('off')
                
                CVSVMModel = crossval(SVMModel);
                [~,scorePred] = kfoldPredict(CVSVMModel);
                outlierRate = mean(scorePred<0)  
            end
        end

        % Value changed function: GscatterDropDown
        function GscatterDropDownValueChanged(app, event)
            value = app.GscatterDropDown.Value;

            if app.IAS_MAPButton.Value == 1
                switch value
                    case 'Location'
                        figure,
                        % h_axes1 = evalin('base','zef.h_axes1');
                        gscatter(app.X1(:,1),app.X1(:,2),app.Y11);
                        xlabel('location difference');
                        ylabel('angle difference');
                        title('location');
                        legend('Thalamus_single','Somatosensory_single','Thalamus_paired','Somatosensory_paired')
                    case  'Scaling Parameter'
                        figure,
                        gscatter(app.X1(:,1),app.X1(:,2),app.Y1);
                        xlabel('location difference');
                        ylabel('angle difference');
                        title('Theta0');
                    case  'Hyperprior'
                        figure,
                        h = gscatter(app.X1(:,1),app.X1(:,2),app.Y21);
                        xlabel('location difference');
                        ylabel('angle difference');
                        legend('Gamma','Inverse Gamma')
                        title('Hyperprior');
                    case  'Noise'
                        figure,
                        gscatter(app.X1(:,2),app.X1(:,3),app.Y31);
                        xlabel('angle difference');
                        ylabel('location difference');
                        title('noise');

                end
            else
                switch value
                    case 'Location'
                        figure,
                        xlabel('location difference');
                        ylabel('angle difference');
                        title('location');
                        legend('Thalamus_single','Somatosensory_single','Thalamus_paired','Somatosensory_paired')
                        % h_axes1 = evalin('base','zef.h_axes1');
                        gscatter(app.X2(:,1),app.X2(:,2),app.Y12);
                    case  'Scaling Parameter'
                        figure,
                        gscatter(app.X2(:,1),app.X2(:,2),app.Y2);
                        xlabel('location difference');
                        ylabel('angle difference');
                        title('Theta0');
                    case  'Hyperprior'
                        figure,
                        gscatter(app.X2(:,1),app.X2(:,2),app.Y22);
                        xlabel('location difference');
                        ylabel('angle difference');
                        legend('Gamma','Inverse Gamma')
                        title('Hyperprior');
                    case  'Noise'
                        figure,
                        gscatter(app.X2(:,2),app.X2(:,3),app.Y32);
                        xlabel('angle difference');
                        ylabel('location difference');
                        title('noise');

                end
            end           
        end

        % Selection changed function: SyntheticDataButtonGroup
        function SyntheticDataButtonGroupSelectionChanged(app, event)
            if event.OldValue ~= event.NewValue
                if app.IAS_MAPButton.Value == 1
                    load('data1') %dipole restruction results with IAS-MAP estimators 
                    app.X1 = X1; % for machine learning training data1(IAS)
                    app.Y1 = Y1; % for theta0 labels data1
                    app.Y11 = Y11; % for whole data1 location labels 
                    app.Y21 = Y21; % for whole data1 location labels 
                    app.Y31 = Y31; % for whole data1 location labels  
                else    
                    load('data2') %dipole restruction results with CM estimators
                    app.X2 = X2; % for machine learning training data2(CM)
                    app.Y2 = Y2; % for theta0 labels data2
                    app.Y12 = Y12 % for whole data2 location labels 
                    app.Y22 = Y22 % for whole data2 location labels 
                    app.Y32 = Y32 % for whole data2 location labels
                end
            end
                
        end

        % Value changed function: EvaluationDropDown
        function EvaluationDropDownValueChanged(app, event)
            value = app.EvaluationDropDown.Value;
            if app.IAS_MAPButton.Value == 1
                Train_data = [app.X1(app.svInd,:),app.Y1(app.svInd)];

                gscatter(Train_data(:,1),Train_data(:,2),Train_data(:,8))
                %%validation
                c = cvpartition(Train_data(:,8), 'k', 5);
            
                %feature selection
                opts = statset('display','iter');
                [~,p,stats] = manova1([Train_data(:,1:3),Train_data(:,5:8)],Train_data(:,8))
                fun = @(train_data, train_labels, test_data, test_labels)...
                    sum(predict(fitcsvm(train_data,train_labels,'KernelFunction','rbf'),test_data) ~= test_labels);
                [app.Features, history] = sequentialfs(fun, [Train_data(:,1:3),Train_data(:,5:7)],Train_data(:,8),'cv',c,'options',opts,'nfeatures',3);

                switch value 
                    case 'SVM scaling parameter prediction'
                        %% SVM classifier
                        rng(1); % For reproducibility
                         
                        %clean training data according to SVM outlier detection
                        Train_data = [app.X1(app.svInd,:),app.Y1(app.svInd)];
                        classes = unique(app.Y1(app.svInd));
                        N = numel(classes);
                        y = {};
                        indx = {}
                        pred = {};
                        %pred = {zeros(size(Y1(index_tr),1),N)};
                        pred = {zeros(size(app.Y1(app.svInd),1),N)};
                        colors = ['r','g']
                        figure
                        
                        %binary classification
                        for j = 1:N
                            
                            %feature selection
                            
                            indx{j} = find(app.Y1(app.svInd)==classes(j));
                            y{j} = (app.Y(indx{j})==classes(j));  % Create binary classes for each classifier
                            %cross-validation,fold number = 3
                            c = cvpartition(Train_data(indx{j},8), 'k', 3);
                            opts = statset('display','iter');
                            [~,p,stats] = manova1([Train_data(indx{j},1:3)],Train_data(indx{j},8)==j)
                            fun = @(train_data, train_labels, test_data, test_labels)...
                                sum(predict(fitcsvm(train_data,train_labels,'KernelFunction','rbf'),test_data) ~= test_labels);
                            [app.Features, history] = sequentialfs(fun, [Train_data(indx{j},1:3),Train_data(indx{j},5:7)],y{j},'cv',c,'options',opts,'nfeatures',3);
                        
                            SVMModels{j} = fitcsvm(app.X(indx{j},app.Features),y{j},'ClassNames',[false true],'Standardize',true,'KernelFunction','gaussian','BoxConstraint',1,'HyperparameterOptimizationOptions',struct('AcquisitionFunctionName','OptimizeHyperparameters','auto',...
                             'expected-improvement-plus','ShowPlots',true));%box = optimizableVariable('box',[1e-4,1e3],'Transform','log','Optimize',true);
                        
                           [pred{j},~] = predict(SVMModels{j},X(indx{j},app.Features));
                        %   gscatter(X((index_tr),3),X((index_tr),2),Y1(index_tr),[(j-1)/N j/N (j-1)/N;(j-1)/N j/N (j-1)/N],'xo');
                        %   [(j-1)/N j/N (j-1)/N;(j-1)/N j/N (j-1)/N]
                            pred{j} = j*pred{j};    
                        %    figure
                            gscatter(app.X1(indx{j},1),app.X1(indx{j},2),pred{j},colors(j),'ox');
                        %    gscatter(X(indx{j},3),X(indx{j},2),pred(indx{j},j) == y{j}(indx{j},:),colors(j),'ox');
                            hold on
                            Pred(j) = sum(pred{j}==y{j})
                        
                        end
                        xlim =[-30 40]
                        ylim =[-40 140]
                        title(' Theta0 prediction');
                        xlabel('location difference');
                        ylabel('angle difference');
                        legend('10^-5','10^-9')
                        set('xlim',xlim);
                        set('ylim',ylim);
                        accuracy = sum(Pred)/length(app.Y1(app.svInd));
                        [h,~] = ttest([pred{1,2};pred{1,3};pred{1,4};pred{1,1};],Train_data(:,8),'Alpha',0.01)
                        
                    case 'Discriminant analysis location prediction'
                        %% SVM classifier
                        rng(1); % For reproducibility
                         
                        %clean training data according to SVM outlier detection
                        Train_data = [app.X1(app.svInd,:),app.Y11(app.svInd)];
                        classes = unique(app.Y11(app.svInd));
                        N = numel(classes);
                        y = {};
                        indx = {}
                        pred = {};
                        %pred = {zeros(size(Y1(index_tr),1),N)};
                        pred = {zeros(size(app.Y11(app.svInd),1),N)};
                        colors = ['r','g']
                        figure
                        
                        %binary classification
                        for j = 1:N
                            
                            %feature selection
                            
                            indx{j} = find(app.Y11(app.svInd)==classes(j));
                            y{j} = (app.Y11(indx{j})==classes(j));  % Create binary classes for each classifier
                            %cross-validation,fold number = 3
                            c = cvpartition(Train_data(indx{j},8), 'k', 3);
                            opts = statset('display','iter');
                            [~,p,stats] = manova1([Train_data(indx{j},1:3)],Train_data(indx{j},8)==j)
                            fun = @(train_data, train_labels, test_data, test_labels)...
                                sum(predict(fitcsvm(train_data,train_labels,'KernelFunction','rbf'),test_data) ~= test_labels);
                            [app.Features, history] = sequentialfs(fun, [Train_data(indx{j},1:3),Train_data(indx{j},5:7)],y{j},'cv',c,'options',opts,'nfeatures',3);
                        
                            SVMModels{j} = fitcsvm(app.X1(indx{j},app.Features),y{j},'ClassNames',[false true],'Standardize',true,'KernelFunction','gaussian','BoxConstraint',1,'HyperparameterOptimizationOptions',struct('AcquisitionFunctionName','OptimizeHyperparameters','auto',...
                             'expected-improvement-plus','ShowPlots',true));%box = optimizableVariable('box',[1e-4,1e3],'Transform','log','Optimize',true);
                        
                           [pred{j},~] = predict(SVMModels{j},app.X1(indx{j},app.Features));
                        %   gscatter(X((index_tr),3),X((index_tr),2),Y1(index_tr),[(j-1)/N j/N (j-1)/N;(j-1)/N j/N (j-1)/N],'xo');
                        %   [(j-1)/N j/N (j-1)/N;(j-1)/N j/N (j-1)/N]
                            pred{j} = j*pred{j};    
                        %    figure
                            gscatter(app.X1(indx{j},1),app.X1(indx{j},2),pred{j},colors(j),'ox');
                        %    gscatter(X(indx{j},3),X(indx{j},2),pred(indx{j},j) == y{j}(indx{j},:),colors(j),'ox');
                            hold on
                            Pred(j) = sum(pred{j}==y{j})
                        
                        end
                        figure
                        xlim =[-30 40]
                        ylim =[-40 140]
                        title('Theta0 prediction');
                        xlabel('location difference');
                        ylabel('angle difference');
                        legend('10^-5','10^-9')
                        accuracy = sum(Pred)/length(app.Y11(app.svInd));
                        [h,~] = ttest([pred{1,2};pred{1,3};pred{1,4};pred{1,1};],Train_data(:,8),'Alpha',0.01)
                        
                   case 'SVM evaluation'
                       %Best hyperparameters Baysopt

                        X_train = app.X1(:,app.Features);
                        %Mdl = fitcsvm(X_test,Train_data(:,8),'KernelFunction','rbf','Lambda','0.01','Learner','leastsquare' ...
                        %    'HyperparameterOptimizationOptions',struct('AcquisitionFunctionName',...
                        %      'expected-improvement-plus','ShowPlots',true));%box = optimizableVariable('box',[1e-4,1e3],'Transform','log','Optimize',true);
                        % Mdl = fitcsvm(X_test,Train_data(:,8),'KernelFunction','gaussian', ...
                        %     'HyperparameterOptimizationOptions',struct('AcquisitionFunctionName','OptimizeHyperparameters','auto',...
                        %      'expected-improvement-plus','ShowPlots',true));
                        box = optimizableVariable('box',[1e-4,1e3],'Transform','log','Optimize',true);
                        kern = optimizableVariable('kern',[1e-4,1e3],'Transform','log','Optimize',true);
                        % hyperparameterResults = optimizableVariable('OptimizationHyperparameters','auto');
                        vars = [box,kern];
                        fun = @(vars)kfoldLoss(fitcsvm(X_train,app.Y1,'BoxConstraint',vars.box,'KernelScale',vars.kern,...
                           'Kfold',5));
                        results = bayesopt(fun,vars,'UseParallel',true);
                        zbest = bestPoint(results);
                        Mdl = fitcsvm(X_train,app.Y1,'BoxConstraint',zbest.box,'KernelScale',zbest.kern);
                        %Mdl = fitcsvm(X_train,Train_data(:,8),[],[],[],[]);
                        
                        
                        %% final test
                        X_test = app.X1(:,app.Features);
                        Y_test = app.Y1;
                        y1 = predict(Mdl, X_test) ;
                        accuracy = sum(y1 ==Y_test)/size(y1,1)
                        
                        % Y_pred = predict(Mdl,X_test);
                        % Y_train = predict(Mdl,X_test);
                        
                        %% hyperplane
                        %%test 
                        %X_test = Test_data(:,app.fs);
                        figure;
                        hgscatter = gscatter(X_train(:,1),X_train(:,2),app.Y1);
                        hold on
                        h_sv=plot(Mdl.SupportVectors(:,1),Mdl.SupportVectors(:,2),'ko','markersize',8);
                        gscatter(X_test(:,1),X_test(:,2),Y_test,'rb','xx');
                        
                        
                        %% 
                        
                        %% decision plane
                        XLIMS = get(gca, 'xlim');
                        YLIMs = get(gca, 'ylim');
                        [xi, yi, zi] = meshgrid([XLIMS(1):1:XLIMS(2)],[YLIMs(1):1:YLIMs(2)],[XLIMS(1):1:XLIMS(2)]);
                        dd = [xi(:), yi(:),zi(:)];
                        pred_mesh = predict(Mdl, dd);
                        redcolor = [1, 0.8, 0.8];
                        bluecolor = [0.8, 0.8, 1];
                        pos = find(pred_mesh == 1);
                        h1 = plot(dd(pos,1), dd(pos,2),'s','color',redcolor,'Markersize',5,'MarkerEdgeColor',redcolor,'MarkerFaceColor',redcolor);
                        pos = find(pred_mesh == 2);
                        h2 = plot(dd(pos,1), dd(pos,2),'s','color',redcolor,'Markersize',5,'MarkerEdgeColor',redcolor,'MarkerFaceColor',redcolor);
                        uistack(h1,'bottom');
                        uistack(h2,'bottom');
                        legend([hgscatter;h_sv],{'locationdiff','anglediff','suppot vectors'});
                        title(' Theta0 prediction');
                        xlabel('location difference');
                        ylabel('angle difference');
                            
                end 
            else 
                Train_data = [app.X2(app.svInd,:),app.Y2(app.svInd)];
        
                gscatter(Train_data(:,1),Train_data(:,2),Train_data(:,8))
                %%validation
                c = cvpartition(Train_data(:,8), 'k', 5);
                    
                %feature selection
                opts = statset('display','iter');
                [~,p,stats] = manova1([Train_data(:,1:3),Train_data(:,5:8)],Train_data(:,8))
                fun = @(train_data, train_labels, test_data, test_labels)...
                    sum(predict(fitcsvm(train_data,train_labels,'KernelFunction','rbf'),test_data) ~= test_labels);
                [app.Features, history] = sequentialfs(fun, [Train_data(:,1:3),Train_data(:,5:7)],Train_data(:,8),'cv',c,'options',opts,'nfeatures',3);

                switch value 
                    case 'SVM scaling parameter prediction'
                        %% SVM classifier
                        rng(1); % For reproducibility
                         
                        %clean training data according to SVM outlier detection
                        Train_data = [app.X2(app.svInd,:),app.Y2(app.svInd)];
                        classes = unique(app.Y2(app.svInd));
                        N = numel(classes);
                        y = {};
                        indx = {}
                        pred = {};
                        %pred = {zeros(size(Y1(index_tr),1),N)};
                        pred = {zeros(size(app.Y2(app.svInd),1),N)};
                        colors = ['r','g']
                        figure
                        
                        %binary classification
                        for j = 1:N
                            
                            %feature selection
                            
                            indx{j} = find(app.Y2(app.svInd)==classes(j));
                            y{j} = (app.Y2(indx{j})==classes(j));  % Create binary classes for each classifier
                            %cross-validation,fold number = 3
                            c = cvpartition(Train_data(indx{j},8), 'k', 3);
                            opts = statset('display','iter');
                            [~,p,stats] = manova1([Train_data(indx{j},1:3)],Train_data(indx{j},8)==j)
                            fun = @(train_data, train_labels, test_data, test_labels)...
                                sum(predict(fitcsvm(train_data,train_labels,'KernelFunction','rbf'),test_data) ~= test_labels);
                            [app.Features, history] = sequentialfs(fun, [Train_data(indx{j},1:3),Train_data(indx{j},5:7)],y{j},'cv',c,'options',opts,'nfeatures',3);
                        
                            SVMModels{j} = fitcsvm(app.X2(indx{j},app.Features),y{j},'ClassNames',[false true],'Standardize',true,'KernelFunction','gaussian','BoxConstraint',1,'HyperparameterOptimizationOptions',struct('AcquisitionFunctionName','OptimizeHyperparameters','auto',...
                             'expected-improvement-plus','ShowPlots',true));%box = optimizableVariable('box',[1e-4,1e3],'Transform','log','Optimize',true);
                        
                           [pred{j},~] = predict(SVMModels{j},app.X2(indx{j},app.Features));
                        %   gscatter(X((index_tr),3),X((index_tr),2),Y1(index_tr),[(j-1)/N j/N (j-1)/N;(j-1)/N j/N (j-1)/N],'xo');
                        %   [(j-1)/N j/N (j-1)/N;(j-1)/N j/N (j-1)/N]
                            pred{j} = j*pred{j};    
                        %    figure
                            gscatter(app.X2(indx{j},1),app.X2(indx{j},2),pred{j},colors(j),'ox');
                        %    gscatter(X(indx{j},3),X(indx{j},2),pred(indx{j},j) == y{j}(indx{j},:),colors(j),'ox');
                            hold on
                            Pred(j) = sum(pred{j}==y{j})
                        
                        end
                        figure,
                        xlim =[-30 40]
                        ylim =[-40 140]
                        title(' Theta0 prediction');
                        xlabel('location difference');
                        ylabel('angle difference');
                        legend('10^-5','10^-9')
                        set('xlim',xlim);
                        set('ylim',ylim);
                        accuracy = sum(Pred)/length(app.Y2(app.svInd));
                        [h,~] = ttest([pred{1,2};pred{1,3};pred{1,4};pred{1,1};],Train_data(:,8),'Alpha',0.01)
                        
                    case 'Discriminant analysis location prediction'
                        %% SVM classifier
                        rng(1); % For reproducibility
                         
                        %clean training data according to SVM outlier detection
                        Train_data = [app.X2(app.svInd,:),app.Y21(app.svInd)];
                        classes = unique(app.Y21(app.svInd));
                        N = numel(classes);
                        y = {};
                        indx = {}
                        pred = {};
                        %pred = {zeros(size(Y1(index_tr),1),N)};
                        pred = {zeros(size(app.Y21(app.svInd),1),N)};
                        colors = ['r','g']
                        figure
                        
                        %binary classification
                        for j = 1:N
                            
                            %feature selection
                            
                            indx{j} = find(app.Y21(app.svInd)==classes(j));
                            y{j} = (app.Y21(indx{j})==classes(j));  % Create binary classes for each classifier
                            %cross-validation,fold number = 3
                            c = cvpartition(Train_data(indx{j},8), 'k', 3);
                            opts = statset('display','iter');
                            [~,p,stats] = manova1([Train_data(indx{j},1:3)],Train_data(indx{j},8)==j)
                            fun = @(train_data, train_labels, test_data, test_labels)...
                                sum(predict(fitcsvm(train_data,train_labels,'KernelFunction','rbf'),test_data) ~= test_labels);
                            [app.Features, history] = sequentialfs(fun, [Train_data(indx{j},1:3),Train_data(indx{j},5:7)],y{j},'cv',c,'options',opts,'nfeatures',3);
                        
                            SVMModels{j} = fitcsvm(app.X2(indx{j},app.Features),y{j},'ClassNames',[false true],'Standardize',true,'KernelFunction','gaussian','BoxConstraint',1,'HyperparameterOptimizationOptions',struct('AcquisitionFunctionName','OptimizeHyperparameters','auto',...
                             'expected-improvement-plus','ShowPlots',true));%box = optimizableVariable('box',[1e-4,1e3],'Transform','log','Optimize',true);
                        
                           [pred{j},~] = predict(SVMModels{j},app.X2(indx{j},app.Features));
                        %   gscatter(X((index_tr),3),X((index_tr),2),Y1(index_tr),[(j-1)/N j/N (j-1)/N;(j-1)/N j/N (j-1)/N],'xo');
                        %   [(j-1)/N j/N (j-1)/N;(j-1)/N j/N (j-1)/N]
                            pred{j} = j*pred{j};    
                            figure
                            gscatter(app.X2(indx{j},1),app.X2(indx{j},2),pred{j},colors(j),'ox');
                        %    gscatter(X(indx{j},3),X(indx{j},2),pred(indx{j},j) == y{j}(indx{j},:),colors(j),'ox');
                            hold on
                            Pred(j) = sum(pred{j}==y{j})
                        
                        end
                        figure,
                        xlim =[-30 40]
                        ylim =[-40 140]
                        title('Theta0 prediction');
                        xlabel('location difference');
                        ylabel('angle difference');
                        legend('10^-5','10^-9')
                        set('xlim',xlim);
                        set('ylim',ylim);
                        accuracy = sum(Pred)/length(app.Y21(app.svInd));
                        [h,~] = ttest([pred{1,2};pred{1,3};pred{1,4};pred{1,1};],Train_data(:,8),'Alpha',0.01)
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Somatosensory = uifigure;
            app.Somatosensory.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.Somatosensory);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Label = uilabel(app.Somatosensory);
            app.Label.Text = '';
            app.DropDown = uidropdown(app.Somatosensory);
            app.DropDown.Items = {'Base Line Correction', 'Base Line Corrected Z_Transformation', 'Base Line Corrected L_Transformation'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Base Line Correction';
            app.coherenceanalysisDropDownLabel = uilabel(app.Somatosensory);
            app.coherenceanalysisDropDownLabel.Text = 'coherence analysis';
            app.coherenceanalysisDropDown = uidropdown(app.Somatosensory);
            app.coherenceanalysisDropDown.Items = {'MS coherence', 'MVAR coherence', 'BIC'};
            app.coherenceanalysisDropDown.ValueChangedFcn = createCallbackFcn(app, @coherenceanalysisDropDownValueChanged, true);
            app.coherenceanalysisDropDown.Value = 'BIC';
            app.DropDown_2 = uilabel(app.Somatosensory);
            app.DropDown_2.Text = 'Preprocessing';
            app.EEGMEGchannelsKnobLabel = uilabel(app.Somatosensory);
            app.EEGMEGchannelsKnobLabel.Text = 'EEG & MEG channels';
            app.EEGMEGchannelsKnob = uiknob(app.Somatosensory, 'discrete');
            app.EEGMEGchannelsKnob.Items = {'5(a)', '33(b)', '18(1)', '27(1)', '13(b)', '5(a)'};
            app.EEGMEGchannelsKnob.ValueChangedFcn = createCallbackFcn(app, @EEGMEGchannelsKnobValueChanged, true);
            app.EEGMEGchannelsKnob.Value = '5(a)';
            app.ClinicalDataButtonGroup = uibuttongroup(app.Somatosensory);
            app.ClinicalDataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ClinicalDataButtonGroupSelectionChanged, true);
            app.ClinicalDataButtonGroup.Title = 'Clinical Data';
            app.EEGButton = uiradiobutton(app.ClinicalDataButtonGroup);
            app.EEGButton.Text = 'EEG';
            app.MEGButton = uiradiobutton(app.ClinicalDataButtonGroup);
            app.MEGButton.Text = 'MEG';
            app.MEGButton.Value = true;
            app.GLMPSEPButton = uibutton(app.Somatosensory, 'push');
            app.GLMPSEPButton.ButtonPushedFcn = createCallbackFcn(app, @GLMPSEPButtonPushed, true);
            app.GLMPSEPButton.Text = 'GLMP SEP';
            app.EditField_2 = uieditfield(app.Somatosensory, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 1200;
            app.EditField_3 = uieditfield(app.Somatosensory, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = 5;
            app.ZeroPadLabel = uilabel(app.Somatosensory);
            app.ZeroPadLabel.Text = 'Zero Pad';
            app.FsLabel = uilabel(app.Somatosensory);
            app.FsLabel.Text = 'Fs';
            app.spectralanalysisDropDownLabel = uilabel(app.Somatosensory);
            app.spectralanalysisDropDownLabel.Text = 'spectral analysis';
            app.spectralanalysisDropDown = uidropdown(app.Somatosensory);
            app.spectralanalysisDropDown.Items = {'(Sensor)_Cross_Spectrum', 'SNR'};
            app.spectralanalysisDropDown.ValueChangedFcn = createCallbackFcn(app, @spectralanalysisDropDownValueChanged, true);
            app.spectralanalysisDropDown.Value = '(Sensor)_Cross_Spectrum';
            app.ForwardSimulationLabel = uilabel(app.Somatosensory);
            app.ForwardSimulationLabel.Text = 'Forward Simulation';
            app.GrangerPredictionProximationDropDownLabel = uilabel(app.Somatosensory);
            app.GrangerPredictionProximationDropDownLabel.Text = 'Granger Prediction Proximation';
            app.GrangerPredictionProximationDropDown = uidropdown(app.Somatosensory);
            app.GrangerPredictionProximationDropDown.Items = {'PDC', 'DTF'};
            app.GrangerPredictionProximationDropDown.ValueChangedFcn = createCallbackFcn(app, @GrangerPredictionProximationDropDownValueChanged, true);
            app.GrangerPredictionProximationDropDown.Value = 'PDC';
            app.GscatterDropDownLabel = uilabel(app.Somatosensory);
            app.GscatterDropDownLabel.Text = 'Gscatter ';
            app.GscatterDropDown = uidropdown(app.Somatosensory);
            app.GscatterDropDown.Items = {'Location', 'Scaling Parameter', 'Hyperprior', 'Noise'};
            app.GscatterDropDown.ValueChangedFcn = createCallbackFcn(app, @GscatterDropDownValueChanged, true);
            app.GscatterDropDown.Value = 'Location';
            app.SyntheticDataButtonGroup = uibuttongroup(app.Somatosensory);
            app.SyntheticDataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SyntheticDataButtonGroupSelectionChanged, true);
            app.SyntheticDataButtonGroup.Title = 'Synthetic Data';
            app.IAS_MAPButton = uiradiobutton(app.SyntheticDataButtonGroup);
            app.IAS_MAPButton.Text = 'IAS_MAP';
            app.IAS_MAPButton.Value = true;
            app.CMButton = uiradiobutton(app.SyntheticDataButtonGroup);
            app.CMButton.Text = 'CM';
            app.EvaluationDropDownLabel = uilabel(app.Somatosensory);
            app.EvaluationDropDownLabel.Text = 'Evaluation';
            app.EvaluationDropDown = uidropdown(app.Somatosensory);
            app.EvaluationDropDown.Items = {'SVM  scaling parameter prediction', 'Discriminant analysis location prediction', 'SVM evaluation'};
            app.EvaluationDropDown.ValueChangedFcn = createCallbackFcn(app, @EvaluationDropDownValueChanged, true);
            app.EvaluationDropDown.Value = 'SVM  scaling parameter prediction';
            app.SVMOutlierDetectionButton = uibutton(app.Somatosensory, 'push');
            app.SVMOutlierDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @SVMOutlierDetectionButtonPushed, true);
            app.SVMOutlierDetectionButton.Text = 'SVM Outlier Detection';
            app.Win_lenEditFieldLabel = uilabel(app.Somatosensory);
            app.Win_lenEditFieldLabel.Text = 'Win_len';
            app.Win_lenEditField = uieditfield(app.Somatosensory, 'numeric');
            app.Win_lenEditField.ValueChangedFcn = createCallbackFcn(app, @Win_lenEditFieldValueChanged, true);
            app.Win_lenEditField.Value = 10;
            app.FpassEditFieldLabel = uilabel(app.Somatosensory);
            app.FpassEditFieldLabel.Text = 'Fpass';
            app.FpassEditField = uieditfield(app.Somatosensory, 'numeric');
            app.FpassEditField.ValueChangedFcn = createCallbackFcn(app, @FpassEditFieldValueChanged, true);
            app.FstopEditFieldLabel = uilabel(app.Somatosensory);
            app.FstopEditFieldLabel.Text = 'Fstop';
            app.FstopEditField = uieditfield(app.Somatosensory, 'numeric');
            app.FstopEditField.ValueChangedFcn = createCallbackFcn(app, @FstopEditFieldValueChanged, true);
            app.TstartEditFieldLabel = uilabel(app.Somatosensory);
            app.TstartEditFieldLabel.Text = 'Tstart';
            app.TstartEditField = uieditfield(app.Somatosensory, 'numeric');
            app.TstartEditField.ValueChangedFcn = createCallbackFcn(app, @TstartEditFieldValueChanged, true);
            app.TendEditFieldLabel = uilabel(app.Somatosensory);
            app.TendEditFieldLabel.Text = 'Tend';
            app.TendEditField = uieditfield(app.Somatosensory, 'numeric');
            app.TendEditField.ValueChangedFcn = createCallbackFcn(app, @TendEditFieldValueChanged, true);
            app.SlideWindowButton = uibutton(app.Somatosensory, 'state');
            app.SlideWindowButton.ValueChangedFcn = createCallbackFcn(app, @SlideWindowButtonValueChanged, true);
            app.SlideWindowButton.Text = 'Slide Window';
            app.HanningWindowButton = uibutton(app.Somatosensory, 'state');
            app.HanningWindowButton.ValueChangedFcn = createCallbackFcn(app, @HanningWindowButtonValueChanged, true);
            app.HanningWindowButton.Text = 'Hanning Window';
            app.cross_lenEditFieldLabel = uilabel(app.Somatosensory);
            app.cross_lenEditFieldLabel.Text = 'cross_len';
            app.cross_lenEditField = uieditfield(app.Somatosensory, 'numeric');
            app.cross_lenEditField.ValueChangedFcn = createCallbackFcn(app, @cross_lenEditFieldValueChanged2, true);
            app.cross_lenEditField.Value = 5;
            app.FreqEditFieldLabel = uilabel(app.Somatosensory);
            app.FreqEditFieldLabel.Text = 'Freq';
            app.FreqEditField = uieditfield(app.Somatosensory, 'numeric');
            app.FreqEditField.ValueChangedFcn = createCallbackFcn(app, @FreqEditFieldValueChanged, true);
            app.Source_cross_SpectrumButton = uibutton(app.Somatosensory, 'push');
            app.Source_cross_SpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @Source_cross_SpectrumButtonPushed, true);
            app.Source_cross_SpectrumButton.Text = 'Source_cross_Spectrum';
        end
    end

    methods (Access = public)

        % Construct app
        function app = somatosensory

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Somatosensory)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Somatosensory)
        end
    end
end